classdef Corona_virus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathesButton              matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        dailyButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        daily_case_death_main_
        cumulative_case_death_
        noc_
        nos_
        names_of_countries
        states_of_australia_
        states_of_canada_
        states_of_china_
        states_of_denmark_
        states_of_france_
        states_of_netherlands_
        states_of_newzealand_
        states_of_unitedkingdom_
        states_of_unitedstates_
        value_1
        value_2
        All
        ShowCases
        ShowDeathes
        ShowBoth
        ShowDaily
        ShowCumulative
        Average
    end
    
    methods (Access = public)
        
        function name_states(app)
             if isequal(app.value_1, 'Australia') 
                 app.StateorRegionListBox.Items = app.states_of_australia_;
             elseif isequal(app.value_1, 'Canada')
                 app.StateorRegionListBox.Items = app.states_of_canada_;
             elseif isequal(app.value_1, 'China')
                 app.StateorRegionListBox.Items = app.states_of_china_;
             elseif isequal(app.value_1, 'Denmark') 
                 app.StateorRegionListBox.Items = app.states_of_denmark_;
             elseif isequal(app.value_1, 'France')
                 app.StateorRegionListBox.Items = app.states_of_france_;
             elseif isequal(app.value_1, 'Netherlands')
                 app.StateorRegionListBox.Items = app.states_of_netherlands_;
             elseif isequal(app.value_1, 'New Zealand')
                 app.StateorRegionListBox.Items = app.states_of_newzealand_;
             elseif isequal(app.value_1, 'United Kingdom')
                 app.StateorRegionListBox.Items = app.states_of_unitedkingdom_;
             elseif isequal(app.value_1, 'United States')
                 app.StateorRegionListBox.Items = app.states_of_unitedstates_;
             else
                 app.StateorRegionListBox.Items = app.All;
             end
            
            
        end
        
        function PlotData(app)
         cla(app.UIAxes,'reset');
         grid(app.UIAxes,'on');
         d1 = '22-01-2020';
         d2 = '28-12-2021';
         formatIn = 'dd-mmm-yyyy';
         XDates = datenum(d1,formatIn): datenum(d2,formatIn);
           if isequal(app.CountryListBox.Value , 'Global') && isequal(app.StateorRegionListBox.Value , 'All')
              ccd_1 = zeros(1,707);
              ccd_2 = zeros(1,707);
              ccd_3 = zeros(1,707);
              ccd_4 = zeros(1,707);
              for i = 1:707
              ccd_1(1,i) = app.daily_case_death_main_{2,i+2}(1,1);
              ccd_2(1,i) = app.daily_case_death_main_{2,i+2}(1,2);
              ccd_3(1,i) = app.cumulative_case_death_{2,i+2}(1,1);
              ccd_4(1,i) = app.cumulative_case_death_{2,i+2}(1,2);
              end
                 if app.Average == 1
                    if app.ShowDaily
                       if app.ShowBoth
                          yyaxis(app.UIAxes, 'left');
                          bar(app.UIAxes,XDates, ccd_1, 'b');
                          yyaxis(app.UIAxes, 'right');
                          plot(app.UIAxes,XDates, ccd_2, 'r');
                       elseif app.ShowCases
                          bar(app.UIAxes,XDates, ccd_1, 'b');
                       elseif app.ShowDeathes
                          plot(app.UIAxes,XDates, ccd_2, 'r');
                       end
                    elseif app.ShowCumulative
                       if app.ShowBoth
                          bar(app.UIAxes,XDates, ccd_3, 'b');
                          yyaxis(app.UIAxes, 'right');
                          plot(app.UIAxes,XDates, ccd_4, 'r');
                          yyaxis(app.UIAxes, 'left');
                       elseif app.ShowCases
                          bar(app.UIAxes,XDates, ccd_3, 'b');                        
                       elseif app.ShowDeathes
                          plot(app.UIAxes,XDates, ccd_4, 'r');
                       end
                    end
                 else
               ccd_1_mean = movmean(ccd_1,[(app.Average)-1 0]);
               ccd_2_mean = movmean(ccd_2,[(app.Average)-1 0]);
               ccd_3_mean = movmean(ccd_3,[(app.Average)-1 0]);
               ccd_4_mean = movmean(ccd_4,[(app.Average)-1 0]);
                    if app.ShowDaily
                       if app.ShowBoth
                          yyaxis(app.UIAxes, 'left');
                          bar(app.UIAxes,XDates, ccd_1_mean, 'b');
                          yyaxis(app.UIAxes, 'right');
                          plot(app.UIAxes,XDates, ccd_2_mean, 'r');
                       elseif app.ShowCases
                          bar(app.UIAxes,XDates, ccd_1_mean, 'b');
                       elseif app.ShowDeathes
                          plot(app.UIAxes,XDates, ccd_2_mean, 'r');
                       end
                    elseif app.ShowCumulative
                       if app.ShowBoth
                          bar(app.UIAxes,XDates, ccd_3_mean, 'b');
                          yyaxis(app.UIAxes, 'right');
                          plot(app.UIAxes,XDates, ccd_4_mean, 'r');
                          yyaxis(app.UIAxes, 'left');
                       elseif app.ShowCases
                          bar(app.UIAxes,XDates, ccd_3_mean, 'b');
                       elseif app.ShowDeathes
                          plot(app.UIAxes,XDates, ccd_4_mean, 'r');
                       end
                    end
                 end
           elseif ~isequal(app.CountryListBox.Value , 'Global') && isequal(app.StateorRegionListBox.Value , 'All') 
                  if isequal(app.value_1 , 'Australia') || isequal(app.value_1 , 'Canada') || isequal(app.value_1 , 'China') || isequal(app.value_1 , 'Denmark') || isequal(app.value_1 , 'France') || isequal(app.value_1 , 'Netherlands') || isequal(app.value_1 , 'New Zealand') || isequal(app.value_1 ,'United Kingdom') || isequal(app.value_1 , 'United States')
                    index = find(contains(app.noc_,app.value_1), 1 );
                    ccd_1 = zeros(1,707);
                    ccd_2 = zeros(1,707);
                    ccd_3 = zeros(1,707);
                    ccd_4 = zeros(1,707);
                    for i = 1:707
                    ccd_1(1,i) = app.daily_case_death_main_{index,i+2}(1,1);
                    ccd_2(1,i) = app.daily_case_death_main_{index,i+2}(1,2);
                    ccd_3(1,i) = app.cumulative_case_death_{index,i+2}(1,1);
                    ccd_4(1,i) = app.cumulative_case_death_{index,i+2}(1,2);
                    end
                       if app.Average == 1
                          if app.ShowDaily
                             if app.ShowBoth
                                yyaxis(app.UIAxes, 'left');
                                bar(app.UIAxes,XDates, ccd_1, 'b');
                                yyaxis(app.UIAxes, 'right');
                                plot(app.UIAxes,XDates, ccd_2, 'r');
                             elseif app.ShowCases
                                bar(app.UIAxes,XDates, ccd_1, 'b');
                             elseif app.ShowDeathes
                                plot(app.UIAxes,XDates, ccd_2, 'r');
                             end
                          elseif app.ShowCumulative
                             if app.ShowBoth
                                bar(app.UIAxes,XDates, ccd_3, 'b');
                                yyaxis(app.UIAxes, 'right');
                                plot(app.UIAxes,XDates, ccd_4, 'r');
                                yyaxis(app.UIAxes, 'left');
                             elseif app.ShowCases
                                bar(app.UIAxes,XDates, ccd_3, 'b');
                             elseif app.ShowDeathes
                                plot(app.UIAxes,XDates, ccd_4, 'r');
                             end
                          end
                       else
                     ccd_1_mean = movmean(ccd_1,[(app.Average)-1 0]);
                     ccd_2_mean = movmean(ccd_2,[(app.Average)-1 0]);
                     ccd_3_mean = movmean(ccd_3,[(app.Average)-1 0]);
                     ccd_4_mean = movmean(ccd_4,[(app.Average)-1 0]);
                         if app.ShowDaily
                            if app.ShowBoth
                               yyaxis(app.UIAxes, 'left');
                               bar(app.UIAxes,XDates, ccd_1_mean, 'b');
                               yyaxis(app.UIAxes, 'right');
                               plot(app.UIAxes,XDates, ccd_2_mean, 'r');
                            elseif app.ShowCases
                               bar(app.UIAxes,XDates, ccd_1_mean, 'b');
                            elseif app.ShowDeathes
                               plot(app.UIAxes,XDates, ccd_2_mean, 'r');
                            end
                         elseif app.ShowCumulative
                            if app.ShowBoth
                               bar(app.UIAxes,XDates, ccd_3_mean, 'b');
                               yyaxis(app.UIAxes, 'right');
                               plot(app.UIAxes,XDates, ccd_4_mean, 'r');
                               yyaxis(app.UIAxes, 'left');
                            elseif app.ShowCases
                               bar(app.UIAxes,XDates, ccd_3_mean, 'b');
                            elseif app.ShowDeathes
                               plot(app.UIAxes,XDates, ccd_4_mean, 'r');
                            end
                         end
                       end
                  else
                    index = find(contains(app.noc_,app.value_1), 1 );
                    ccd_1 = zeros(1,707);
                    ccd_2 = zeros(1,707);
                    ccd_3 = zeros(1,707);
                    ccd_4 = zeros(1,707);
                    for i = 1:707
                    ccd_1(1,i) = app.daily_case_death_main_{index,i+2}(1,1);
                    ccd_2(1,i) = app.daily_case_death_main_{index,i+2}(1,2);
                    ccd_3(1,i) = app.cumulative_case_death_{index,i+2}(1,1);
                    ccd_4(1,i) = app.cumulative_case_death_{index,i+2}(1,2);
                    end
                      if app.Average == 1
                        if app.ShowDaily
                          if app.ShowBoth
                             yyaxis(app.UIAxes, 'left');
                             bar(app.UIAxes,XDates, ccd_1, 'b');
                             yyaxis(app.UIAxes, 'right');
                             plot(app.UIAxes,XDates, ccd_2, 'r');
                          elseif app.ShowCases
                             bar(app.UIAxes,XDates, ccd_1, 'b');
                          elseif app.ShowDeathes
                             plot(app.UIAxes,XDates, ccd_2, 'r');
                          end
                        elseif app.ShowCumulative
                          if app.ShowBoth
                             bar(app.UIAxes,XDates, ccd_3, 'b');
                             yyaxis(app.UIAxes, 'right');
                             plot(app.UIAxes,XDates, ccd_4, 'r');
                             yyaxis(app.UIAxes, 'left');
                          elseif app.ShowCases
                             bar(app.UIAxes,XDates, ccd_3, 'b');
                          elseif app.ShowDeathes
                             plot(app.UIAxes,XDates, ccd_4, 'r');
                          end
                        end
                      else
                    ccd_1_mean = movmean(ccd_1,[(app.Average)-1 0]);
                    ccd_2_mean = movmean(ccd_2,[(app.Average)-1 0]);
                    ccd_3_mean = movmean(ccd_3,[(app.Average)-1 0]);
                    ccd_4_mean = movmean(ccd_4,[(app.Average)-1 0]);
                       if app.ShowDaily
                          if app.ShowBoth
                             yyaxis(app.UIAxes, 'left');
                             bar(app.UIAxes,XDates, ccd_1_mean, 'b');
                             yyaxis(app.UIAxes, 'right');
                             plot(app.UIAxes,XDates, ccd_2_mean, 'r');
                          elseif app.ShowCases
                             bar(app.UIAxes,XDates, ccd_1_mean, 'b');
                          elseif app.ShowDeathes
                             plot(app.UIAxes,XDates, ccd_2_mean, 'r');
                          end
                       elseif app.ShowCumulative
                          if app.ShowBoth
                             bar(app.UIAxes,XDates, ccd_3_mean, 'b');
                             yyaxis(app.UIAxes, 'right');
                             plot(app.UIAxes,XDates, ccd_4_mean, 'r');
                             yyaxis(app.UIAxes, 'left');
                          elseif app.ShowCases
                             bar(app.UIAxes,XDates, ccd_3_mean, 'b');
                          elseif app.ShowDeathes
                             plot(app.UIAxes,XDates, ccd_4_mean, 'r');
                          end
                       end
                      end
                  end

           else
                  for i = 1:340
                      if isequal(app.nos_{i,1},app.value_2)
                         index = i;
                      end
                  end
                    ccd_1 = zeros(1,707);
                    ccd_2 = zeros(1,707);
                    ccd_3 = zeros(1,707);
                    ccd_4 = zeros(1,707);
                    for i = 1:707
                    ccd_1(1,i) = app.daily_case_death_main_{index,i+2}(1,1);
                    ccd_2(1,i) = app.daily_case_death_main_{index,i+2}(1,2);
                    ccd_3(1,i) = app.cumulative_case_death_{index,i+2}(1,1);
                    ccd_4(1,i) = app.cumulative_case_death_{index,i+2}(1,2);
                    end
                    if app.Average == 1
                       if app.ShowDaily
                          if app.ShowBoth
                             yyaxis(app.UIAxes, 'left');
                             bar(app.UIAxes,XDates, ccd_1, 'b');
                             yyaxis(app.UIAxes, 'right');
                             plot(app.UIAxes,XDates, ccd_2, 'r');
                          elseif app.ShowCases
                             bar(app.UIAxes,XDates, ccd_1, 'b');
                          elseif app.ShowDeathes
                             plot(app.UIAxes,XDates, ccd_2, 'r');
                          end
                       elseif app.ShowCumulative
                          if app.ShowBoth
                             bar(app.UIAxes,XDates, ccd_3, 'b');
                             yyaxis(app.UIAxes, 'right');
                             plot(app.UIAxes,XDates, ccd_4, 'r');
                             yyaxis(app.UIAxes, 'left');
                          elseif app.ShowCases
                             bar(app.UIAxes,XDates, ccd_3, 'b');
                          elseif app.ShowDeathes
                             plot(app.UIAxes,XDates, ccd_4, 'r');
                          end
                       end
                    else
                      ccd_1_mean = movmean(ccd_1,[(app.Average)-1 0]);
                      ccd_2_mean = movmean(ccd_2,[(app.Average)-1 0]);
                      ccd_3_mean = movmean(ccd_3,[(app.Average)-1 0]);
                      ccd_4_mean = movmean(ccd_4,[(app.Average)-1 0]);
                      if app.ShowDaily
                         if app.ShowBoth
                            yyaxis(app.UIAxes, 'left');
                            bar(app.UIAxes,XDates, ccd_1_mean, 'b');
                            yyaxis(app.UIAxes, 'right');
                            plot(app.UIAxes,XDates, ccd_2_mean, 'r');
                         elseif app.ShowCases
                            bar(app.UIAxes,XDates, ccd_1_mean, 'b');
                         elseif app.ShowDeathes
                            plot(app.UIAxes,XDates, ccd_2_mean, 'r');
                         end
                      elseif app.ShowCumulative
                         if app.ShowBoth
                            bar(app.UIAxes,XDates, ccd_3_mean, 'b');
                            yyaxis(app.UIAxes, 'right');
                            plot(app.UIAxes,XDates, ccd_4_mean, 'r');
                            yyaxis(app.UIAxes, 'left');
                         elseif app.ShowCases
                            bar(app.UIAxes,XDates, ccd_3_mean, 'b');
                         elseif app.ShowDeathes
                            plot(app.UIAxes,XDates, ccd_4_mean, 'r');
                         end
                      end
                    end

           end
           datetick(app.UIAxes,'x',2,'keepticks','keeplimits');
        end
        
        function title_of_plot(app)
            if isequal(app.CountryListBox.Value , 'Global') && isequal(app.StateorRegionListBox.Value , 'All')
                if app.Average == 1
                    if app.ShowDaily
                        if app.ShowBoth
                            title(app.UIAxes,'Daily number of cases and deaths globally');
                        elseif app.ShowCases
                            title(app.UIAxes,'Daily number of cases globally');
                        elseif app.ShowDeathes
                            title(app.UIAxes,'Daily number of deaths globally');
                        end
                    elseif app.ShowCumulative
                        if app.ShowBoth
                            title(app.UIAxes,'Cumulative number of cases and deaths globally');
                        elseif app.ShowCases
                            title(app.UIAxes,'Cumulative number of cases globally'); 
                        elseif app.ShowDeathes
                            title(app.UIAxes,'Daily number of deaths globally');
                        end
                    end
                else
                    average_str = string(app.Average);
                    if app.ShowDaily
                       if app.ShowBoth
                           str1 = {" Daily number of cases and deaths in average number of "};
                           str2 = {average_str};
                           str = strcat(str1,str2);
                           title(app.UIAxes, str{1,1});
                       elseif app.ShowCases
                           str1 = {" Daily number of cases in average number of "};
                           str2 = {average_str};
                           str = strcat(str1,str2);
                           title(app.UIAxes, str{1,1});
                       elseif app.ShowDeathes
                           str1 = {" Daily number of deaths in average number of "};
                           str2 = {average_str};
                           str = strcat(str1,str2);
                           title(app.UIAxes, str{1,1});
                       end
                    elseif app.ShowCumulative
                        if app.ShowBoth
                           str1 = {" Cumulative number of cases and deaths in average number of "};
                           str2 = {average_str};
                           str = strcat(str1,str2);
                           title(app.UIAxes, str{1,1});
                        elseif app.ShowCases
                           str1 = {" cumulative number of cases in average number of "};
                           str2 = {average_str};
                           str = strcat(str1,str2);
                           title(app.UIAxes, str{1,1});
                        elseif app.ShowDeathes
                           str1 = {" Cumulative number of deaths in average number of "};
                           str2 = {average_str};
                           str = strcat(str1,str2);
                           title(app.UIAxes, str{1,1});
                        end
                    end
                end
            elseif ~isequal(app.CountryListBox.Value , 'Global') && isequal(app.StateorRegionListBox.Value , 'All') 
                  if isequal(app.value_1 , 'Australia') || isequal(app.value_1 , 'Canada') || isequal(app.value_1 , 'China') || isequal(app.value_1 , 'Denmark') || isequal(app.value_1 , 'France') || isequal(app.value_1 , 'Netherlands') || isequal(app.value_1 , 'New Zealand') || isequal(app.value_1 ,'United Kingdom') || isequal(app.value_1 , 'United States')
                      index = find(contains(app.noc_,app.value_1), 1 );
                      str1 = {string(app.noc_{index,1})};
                      if app.Average == 1
                          if app.ShowDaily
                              if app.ShowBoth
                                  str2 = {" Daily number of cases and deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Daily number of cases in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Daily number of deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              end
                          elseif app.ShowCumulative
                              if app.ShowBoth
                                  str2 = {" Cumulative number of cases and deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Cumulative number of cases in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Cumulative number of deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              end
                          end
                      else
                          average_str = string(app.Average);
                           if app.ShowDaily
                              if app.ShowBoth
                                  str2 = {" Daily number of cases and deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Daily number of cases in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Daily number of deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              end
                           elseif app.ShowCumulative
                               if app.ShowBoth
                                  str2 = {" Cumulative number of cases and deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Cumulative number of cases in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Cumulative number of deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                               end
                           end
                      end
                  else
                      index = find(contains(app.noc_,app.value_1), 1 );
                      str1 = {string(app.noc_{index,1})};
                      if app.Average == 1
                          if app.ShowDaily
                              if app.ShowBoth
                                  str2 = {" Daily number of cases and deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Daily number of cases in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Daily number of deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              end
                          elseif app.ShowCumulative
                              if app.ShowBoth
                                  str2 = {" Cumulative number of cases and deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Cumulative number of cases in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Cumulative number of deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              end
                          end
                      else
                          average_str = string(app.Average);
                           if app.ShowDaily
                              if app.ShowBoth
                                  str2 = {" Daily number of cases and deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Daily number of cases in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Daily number of deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              end
                           elseif app.ShowCumulative
                               if app.ShowBoth
                                  str2 = {" Cumulative number of cases and deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Cumulative number of cases in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Cumulative number of deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                               end
                           end
                      end
                  end
            else
                for i = 1:340
                      if isequal(app.nos_{i,1},app.value_2)
                         index = i;
                      end
                end
                      str1 = {string(app.nos_{index,1})};
                      if app.Average == 1
                          if app.ShowDaily
                              if app.ShowBoth
                                  str2 = {" Daily number of cases and deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Daily number of cases in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Daily number of deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              end
                          elseif app.ShowCumulative
                              if app.ShowBoth
                                  str2 = {" Cumulative number of cases and deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Cumulative number of cases in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Cumulative number of deaths in "};
                                  str = strcat(str2,str1);
                                  title(app.UIAxes, str{1,1});
                              end
                          end
                      else
                          average_str = string(app.Average);
                           if app.ShowDaily
                              if app.ShowBoth
                                  str2 = {" Daily number of cases and deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Daily number of cases in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Daily number of deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              end
                           elseif app.ShowCumulative
                               if app.ShowBoth
                                  str2 = {" Cumulative number of cases and deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowCases
                                  str2 = {" Cumulative number of cases in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                              elseif app.ShowDeathes
                                  str2 = {" Cumulative number of deaths in average number of "};
                                  str3 = {average_str};
                                  str4 = {" in "};
                                  str = strcat(str2,str3,str4,str1);
                                  title(app.UIAxes,str{1,1});
                               end
                           end
                      end
            end                           
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load iKxt-dBOQfisbfnQTkH48A_37ac1662db254c17841c5c0cc420eef1_covid_data.mat covid_data;
            Names_of_countries = cell(1,338);
             for i = 1:338
                 Names_of_countries{1,i} = covid_data{i+1,1};
             end
             Names_of_countries([10:17,42:57,62:95,107:108,123:133,197:200,202,262:272,274:329]) = [];
             Names_of_countries_main{1,1} = 'Global';
             for i = 2:length(Names_of_countries)+1
                 Names_of_countries_main{1,i} =  Names_of_countries{1,i-1};
             end
             app.names_of_countries = Names_of_countries_main;
             app.CountryListBox.Items = app.names_of_countries;       %startup list of countries


             all = cell(1,1);
             all{1,1} = 'All';
             app.All = all;


             all = cell(1,1);
             all{1,1} = 'All';
             app.StateorRegionListBox.Items = all;             %startup list of states


             NOC = cell(1,338);
             for i = 1:338
                 NOC{1,i} = covid_data{i+1,1};
             end
             NOS = cell(1,338);
             for i = 1:338
             NOS{1,i} = covid_data{i+1,2};
             end


             indices_australia = find(contains(NOC,'Australia'));
             states_of_australia = cell(1,length(indices_australia));
             states_of_australia{1,1} = 'All';
             for i = 2:length(indices_australia)
             states_of_australia{1,i} = NOS{1,indices_australia(1,i)};
             app.states_of_australia_ = states_of_australia;
             end


             indices_canada = find(contains(NOC,'Canada'));
             states_of_canada = cell(1,length(indices_canada));
             states_of_canada{1,1} = 'All';
             for i = 2:length(indices_canada)
             states_of_canada{1,i} = NOS{1,indices_canada(1,i)};
             app.states_of_canada_ = states_of_canada;
             end


             indices_china = find(contains(NOC,'China'));
             states_of_china = cell(1,length(indices_china));
             states_of_china{1,1} = 'All';
             for i = 2:length(indices_china)
             states_of_china{1,i} = NOS{1,indices_china(1,i)};
             app.states_of_china_ = states_of_china;
             end


             indices_denmark = find(contains(NOC,'Denmark'));
             states_of_denmark = cell(1,length(indices_denmark));
             states_of_denmark{1,1} = 'All';
             for i = 2:length(indices_denmark)
             states_of_denmark{1,i} = NOS{1,indices_denmark(1,i)};
             app.states_of_denmark_ = states_of_denmark;
             end


             indices_france = find(contains(NOC,'France'));
             states_of_france = cell(1,length(indices_france));
             states_of_france{1,1} = 'All';
             for i = 2:length(indices_france)
             states_of_france{1,i} = NOS{1,indices_france(1,i)};
             app.states_of_france_ = states_of_france;
             end


             indices_netherlands = find(contains(NOC,'Netherlands'));
             states_of_netherlands = cell(1,length(indices_netherlands));
             states_of_netherlands{1,1} = 'All';
             for i = 2:length(indices_netherlands)
             states_of_netherlands{1,i} = NOS{1,indices_netherlands(1,i)};
             app.states_of_netherlands_ = states_of_netherlands;
             end


             indices_newzealand = find(contains(NOC,'New Zealand'));
             states_of_newzealand = cell(1,length(indices_newzealand));
             states_of_newzealand{1,1} = 'All';
             for i = 2:length(indices_newzealand)
             states_of_newzealand{1,i} = NOS{1,indices_newzealand(1,i)};
             app.states_of_newzealand_ = states_of_newzealand;
             end


             indices_unitedkingdom = find(contains(NOC,'United Kingdom'));
             states_of_unitedkingdom = cell(1,length(indices_unitedkingdom));
             states_of_unitedkingdom{1,1} = 'All';
             for i = 2:length(indices_unitedkingdom)
             states_of_unitedkingdom{1,i} = NOS{1,indices_unitedkingdom(1,i)};
             app.states_of_unitedkingdom_ = states_of_unitedkingdom;
             end


             indices_unitedstates = find(contains(NOC,'United States'));
             states_of_unitedstates = cell(1,length(indices_denmark));
             states_of_unitedstates{1,1} = 'All';
             for i = 2:length(indices_unitedstates)
             states_of_unitedstates{1,i} = NOS{1,indices_unitedstates(1,i)};
             app.states_of_unitedstates_ = states_of_unitedstates;
             end


             daily_case_death_helper = covid_data;
for i = 2:339
    for j = 4:709
        daily_case_death_helper{i,j}(1,1) = covid_data{i,j}(1,1) - covid_data{i,j-1}(1,1);
        daily_case_death_helper{i,j}(1,2) = covid_data{i,j}(1,2) - covid_data{i,j-1}(1,2);
    end
end
daily_case_death_main = cell(340,709);
for j = 1:709
    daily_case_death_main{1,j} = daily_case_death_helper{1,j};
end
for i = 3:340
    for j = 3:709
        daily_case_death_main{i,j}(1,1) = daily_case_death_helper{i-1,j}(1,1);
        daily_case_death_main{i,j}(1,2) = daily_case_death_helper{i-1,j}(1,2);
    end
end
daily_case_death_main{2,1} = 'global';
daily_case_death_main{2,2} = [];
for j = 3:709
    sum_1 = 0;
    sum_2 = 0;
    for i = 3:340
    sum_1 = sum_1 + daily_case_death_helper{i-1,j}(1,1);
    sum_2 = sum_2 + daily_case_death_helper{i-1,j}(1,2);
    end
    daily_case_death_main{2,j}(1,1) = sum_1 - daily_case_death_helper{10,j}(1,1) - daily_case_death_helper{42,j}(1,1) - daily_case_death_helper{62,j}(1,1) - daily_case_death_helper{107,j}(1,1) - daily_case_death_helper{123,j}(1,1) - daily_case_death_helper{197,j}(1,1) - daily_case_death_helper{202,j}(1,1) - daily_case_death_helper{262,j}(1,1) - daily_case_death_helper{274,j}(1,1);
    daily_case_death_main{2,j}(1,2) = sum_2 - daily_case_death_helper{10,j}(1,2) - daily_case_death_helper{42,j}(1,2) - daily_case_death_helper{62,j}(1,2) - daily_case_death_helper{107,j}(1,2) - daily_case_death_helper{123,j}(1,2) - daily_case_death_helper{197,j}(1,2) - daily_case_death_helper{202,j}(1,2) - daily_case_death_helper{262,j}(1,2) - daily_case_death_helper{274,j}(1,2);
end
for i = 3:340
    daily_case_death_main{i,1} = daily_case_death_helper{i-1,1};
end
for i = 3:340
    daily_case_death_main{i,2} = daily_case_death_helper{i-1,2};
end
                   app.daily_case_death_main_ = daily_case_death_main;


              cumulative_case_death = cell(340,709);
for j = 1:709
    cumulative_case_death{1,j} = covid_data{1,j};
end
for i = 3:340
    for j = 3:709
        cumulative_case_death{i,j}(1,1) = covid_data{i-1,j}(1,1);
        cumulative_case_death{i,j}(1,2) = covid_data{i-1,j}(1,2);
    end
end
cumulative_case_death{2,1} = 'global';
cumulative_case_death{2,2} = [];
for j = 3:709
    sum_1 = 0;
    sum_2 = 0;
    for i = 3:340
    sum_1 = sum_1 + covid_data{i-1,j}(1,1);
    sum_2 = sum_2 + covid_data{i-1,j}(1,2);
    end
    cumulative_case_death{2,j}(1,1) = sum_1 - covid_data{10,j}(1,1) - covid_data{42,j}(1,1) - covid_data{62,j}(1,1) - covid_data{107,j}(1,1) - covid_data{123,j}(1,1) - covid_data{197,j}(1,1) - covid_data{202,j}(1,1) - covid_data{262,j}(1,1) - covid_data{274,j}(1,1);
    cumulative_case_death{2,j}(1,2) = sum_2 - covid_data{10,j}(1,2) - covid_data{42,j}(1,2) - covid_data{62,j}(1,2) - covid_data{107,j}(1,2) - covid_data{123,j}(1,2) - covid_data{197,j}(1,2) - covid_data{202,j}(1,2) - covid_data{262,j}(1,2) - covid_data{274,j}(1,2);
end
for i = 3:340
    cumulative_case_death{i,1} = covid_data{i-1,1};
end
for i = 3:340
    cumulative_case_death{i,2} = covid_data{i-1,2};
end
                     app.cumulative_case_death_ = cumulative_case_death;


noc = cell(340,1);
for i = 1:340
    noc{i,1} = cumulative_case_death{i,1};
end
              app.noc_ = noc;


nos = cell(340,1);
for i = 1:340
    nos{i,1} = cumulative_case_death{i,2};
end
                app.nos_ = nos;

                
app.ShowCases = app.CasesButton.Value ;
app.ShowDeathes = app.DeathesButton.Value ;
app.ShowBoth = app.BothButton.Value; 
app.ShowDaily = app.dailyButton.Value;
app.ShowCumulative = app.CumulativeButton.Value;
app.CountryListBox.Value = 'Global';
app.StateorRegionListBox.Value = 'All';
app.Average = app.AveragedofdaysSlider.Value;
app.PlotData();
app.title_of_plot();        
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.ShowDaily = app.dailyButton.Value;
            app.ShowCumulative = app.CumulativeButton.Value;
            app.PlotData();
            app.title_of_plot();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value ;
            app.ShowDeathes = app.DeathesButton.Value ;
            app.ShowBoth = app.BothButton.Value;
            app.PlotData();
            app.title_of_plot();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.value_1 = event.Value;
            app.name_states();
            app.PlotData();
            app.title_of_plot();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.value_2 = event.Value;
            app.PlotData();
            app.title_of_plot();           
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
    value = app.AveragedofdaysSlider.Value;
    [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
    event.Source.Value = event.Source.MajorTicks(minIdx);
    app.Average = event.Source.Value;   
    app.PlotData();
    app.title_of_plot();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cumulative Number Of Cases')
            xlabel(app.UIAxes, 'Day')
            ylabel(app.UIAxes, 'Number of cases or deathes')
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = {'State or'; 'Region :'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [1 1.4 1.8 2.2 2.6 3 3.4 3.8 4.2 4.6 5 5.4 5.8 6.2 6.6 7 7.4 7.8 8.2 8.6 9 9.4 9.8 10.2 10.6 11 11.4 11.8 12.2 12.6 13 13.4 13.8 14.2 14.6 15];
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathesButton.Text = 'Deathes';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.dailyButton = uiradiobutton(app.OptionButtonGroup);
            app.dailyButton.Text = 'daily';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Corona_virus

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end